classdef foci_analizer_V3_forfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        focianalysisUIFigure       matlab.ui.Figure
        Step5SavingPanel           matlab.ui.container.Panel
        SaveButton                 matlab.ui.control.Button
        Panel_3                    matlab.ui.container.Panel
        maxdistSlider              matlab.ui.control.Slider
        maxdistSliderLabel         matlab.ui.control.Label
        UIAxes_coloc               matlab.ui.control.UIAxes
        Step4analyzePanel          matlab.ui.container.Panel
        randomizedLabel            matlab.ui.control.Label
        signalLabel                matlab.ui.control.Label
        Channel2Label              matlab.ui.control.Label
        Channel1Label              matlab.ui.control.Label
        NbootstrapEditField        matlab.ui.control.NumericEditField
        NbootstrapEditFieldLabel   matlab.ui.control.Label
        WindowsizeEditField        matlab.ui.control.NumericEditField
        WindowsizeEditFieldLabel   matlab.ui.control.Label
        ProcessButton              matlab.ui.control.Button
        UIAxes_Signal1             matlab.ui.control.UIAxes
        UIAxes_Signal2             matlab.ui.control.UIAxes
        UIAxes_Random2             matlab.ui.control.UIAxes
        UIAxes_Random1             matlab.ui.control.UIAxes
        UIAxes_Scatter             matlab.ui.control.UIAxes
        UIAxes_Hist                matlab.ui.control.UIAxes
        UIAxes_BoxPlot             matlab.ui.control.UIAxes
        UIAxes_Plot                matlab.ui.control.UIAxes
        Panel_2                    matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        Step3findpeaksPanel        matlab.ui.container.Panel
        Slider_3                   matlab.ui.control.Slider
        Slider_2                   matlab.ui.control.Slider
        thresholdforpeaksLabel     matlab.ui.control.Label
        distancebetweenpeaksLabel  matlab.ui.control.Label
        Step2optionalPanel         matlab.ui.container.Panel
        cropButton                 matlab.ui.control.Button
        Step1segmentnucleiPanel    matlab.ui.container.Panel
        ManualsegmentationButton   matlab.ui.control.Button
        Slider                     matlab.ui.control.Slider
        choosechanelDropDown       matlab.ui.control.DropDown
        choosechanelDropDownLabel  matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        pathfileEditField_2        matlab.ui.control.EditField
        pathfileEditField_2Label   matlab.ui.control.Label
        loadChannel2Button         matlab.ui.control.Button
        loadChannel1Button         matlab.ui.control.Button
        pathfileEditField          matlab.ui.control.EditField
        pathfileEditFieldLabel     matlab.ui.control.Label
    end


    properties (Access = private)
        PathName1 % Path for loading images
        FileName1 % file name
        ImCurrent1 % current image
        IW % image width
        IH % image height
        PathName2 % Path for loading images
        FileName2 % file name
        ImCurrent2 % current image
        IR2 % raw image 2
        DefPath % path by default
        ImMask % mask
        Thresh % threshold for segementation
        Dist % distance between peaks
        Th % threshold for peaks
        Peaks % peaks
        Peaks2 % peaks of channel 2
        NP % number of peaks
        NP2 % number of peaks of channel 2
        WindowSize % size of the window for analysis
        Nrep % number of repetitions for randomization
        RadialProfile % radial profiles
        AllPeaks % central values of all peaks
        AllPeaks2 % central values of all peaks channel 2
        AllRandomPeaks % central values of all peaks
        Fpp % fraction of positive peaks
        ChannelToSegment % channel used for segmentation
        FColoc % fraction of peaks that colocalize
        FColocRand % fraction of peaks that colocalize just by chance
        MaxDist % maximum distance to be considered as colocalizing
    end

    methods (Access = private)

        function findpeaks(app)
            %d = uint16(app.ImCurrent1);
            d=medfilt2(app.ImCurrent1,[3,3]);
            d=d.*(d>app.Th);
            edg =3;
            filt = (fspecial('gaussian', 20,app.Dist));
            d=conv2(single(d),filt,'same') ;
            d=d.*(d>0.9*app.Th);
            sd=size(d);
            [x y]=find(d(edg:sd(1)-edg,edg:sd(2)-edg));
            % initialize outputs
            cent=[];%
            x=x+edg-1;
            y=y+edg-1;
            n=0;
            for j=1:length(y)
                if (d(x(j),y(j))>=d(x(j)-1,y(j)-1 )) &&...
                        (d(x(j),y(j))>d(x(j)-1,y(j))) &&...
                        (d(x(j),y(j))>=d(x(j)-1,y(j)+1)) &&...
                        (d(x(j),y(j))>d(x(j),y(j)-1)) && ...
                        (d(x(j),y(j))>d(x(j),y(j)+1)) && ...
                        (d(x(j),y(j))>=d(x(j)+1,y(j)-1)) && ...
                        (d(x(j),y(j))>d(x(j)+1,y(j))) && ...
                        (d(x(j),y(j))>=d(x(j)+1,y(j)+1) && ...
                        app.ImMask(x(j),y(j))==1);
                    cent = [cent ;  y(j) ; x(j)];
                    n=n+1;
                end
            end
            app.Peaks = cent;
            app.NP = n;
        end

        function findpeaks2(app)
            %d = uint16(app.ImCurrent1);
            d=medfilt2(app.ImCurrent2,[3,3]);
            d=d.*(d>app.Th);
            edg =3;
            filt = (fspecial('gaussian', 20,app.Dist));
            d=conv2(single(d),filt,'same') ;
            d=d.*(d>0.9*app.Th);
            sd=size(d);
            [x y]=find(d(edg:sd(1)-edg,edg:sd(2)-edg));
            % initialize outputs
            cent=[];%
            x=x+edg-1;
            y=y+edg-1;
            n=0;
            for j=1:length(y)
                if (d(x(j),y(j))>=d(x(j)-1,y(j)-1 )) &&...
                        (d(x(j),y(j))>d(x(j)-1,y(j))) &&...
                        (d(x(j),y(j))>=d(x(j)-1,y(j)+1)) &&...
                        (d(x(j),y(j))>d(x(j),y(j)-1)) && ...
                        (d(x(j),y(j))>d(x(j),y(j)+1)) && ...
                        (d(x(j),y(j))>=d(x(j)+1,y(j)-1)) && ...
                        (d(x(j),y(j))>d(x(j)+1,y(j))) && ...
                        (d(x(j),y(j))>=d(x(j)+1,y(j)+1) && ...
                        app.ImMask(x(j),y(j))==1);
                    cent = [cent ;  y(j) ; x(j)];
                    n=n+1;
                end
            end
            app.Peaks2 = cent;
            app.NP2 = n;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Dist = 0;
            app.Th = 0;
            app.WindowSize = 20;
            app.Nrep = 1;
            app.MaxDist = 3;
            app.Thresh = 0;
            app.ChannelToSegment = 'Channel 1';
            app.ImCurrent1 = zeros(100,100);
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
            axis(app.UIAxes,'tight')
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes2);
            axis(app.UIAxes2,'tight')
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes_Signal1);
            axis(app.UIAxes_Signal1,'tight')
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes_Signal2);
            axis(app.UIAxes_Signal2,'tight')
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes_Random1);
            axis(app.UIAxes_Random1,'tight')
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes_Random2);
            axis(app.UIAxes_Random2,'tight')
        end

        % Button pushed function: loadChannel1Button
        function loadChannel1ButtonPushed(app, event)
            if isempty(app.DefPath)
                app.DefPath = 'C:\Users\Coppey\OneDrive - INSTITUT CURIE\Documents\WORK\MATLAB\FOCI';
            end
            [app.FileName1,app.PathName1,~] = uigetfile('*.tif','Select the tif file',app.DefPath);
            app.DefPath = app.PathName1;
            app.pathfileEditField.Value = app.FileName1;
            app.ImCurrent1 = imread([app.PathName1,app.FileName1]);
            app.ImCurrent1 = imadjust(uint16(app.ImCurrent1),stretchlim(uint16(app.ImCurrent1),0),[0 1]);
            app.ImCurrent1 = double(app.ImCurrent1);
            Isort = sort(app.ImCurrent1(:));
            cutsize = 1;
            mini = mean(Isort(1:cutsize));
            maxi = mean(Isort(end-cutsize:end));
            app.ImCurrent1 = (app.ImCurrent1-mini)./(maxi-mini);
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
            axis(app.UIAxes,'tight')

            si = size(app.ImCurrent1);
            app.IW = si(1);
            app.IH = si(2);
            figure(app.focianalysisUIFigure)
        end

        % Button pushed function: loadChannel2Button
        function loadChannel2ButtonPushed(app, event)
            if isempty(app.DefPath)
                app.DefPath = 'C:\Users\Coppey\OneDrive - INSTITUT CURIE\Documents\WORK\MATLAB\FOCI';
            end
            [app.FileName2,app.PathName2,~] = uigetfile('*.tif','Select the tif file',app.DefPath);
            app.DefPath = app.PathName2;
            app.pathfileEditField_2.Value = app.FileName2;
            app.ImCurrent2 = imread([app.PathName2,app.FileName2]);
            app.IR2 = app.ImCurrent2;
            app.ImCurrent2 = imadjust(uint16(app.ImCurrent2),stretchlim(uint16(app.ImCurrent2),0),[0 1]);
            app.ImCurrent2 = double(app.ImCurrent2);
            Isort = sort(app.ImCurrent2(:));
            cutsize = 1;
            mini = mean(Isort(1:cutsize));
            maxi = mean(Isort(end-cutsize:end));
            app.ImCurrent2 = (app.ImCurrent2-mini)./(maxi-mini);
            imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
            axis(app.UIAxes2,'tight')
            figure(app.focianalysisUIFigure)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.Thresh = event.Value;
            if app.ChannelToSegment=='Channel 1'
                f1 = fspecial('Gaussian', 10,3);
                f2 = fspecial('Gaussian', 10, 5);
                df = f1-2.*f2;
                bwo = conv2(app.ImCurrent1, df, 'same');
                bwo = imcomplement(bwo);
                bwo = bwo./max(max(bwo));
                th = graythresh(bwo)+app.Thresh/1000;
                app.ImMask = imbinarize(bwo,th);
                app.ImMask = bwareaopen(app.ImMask,round(app.IW*app.IH/10));
                se = strel('disk',10);
                app.ImMask = imclose(app.ImMask,se);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
                hold(app.UIAxes,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes,'off')
            else
                f1 = fspecial('Gaussian', 10,3);
                f2 = fspecial('Gaussian', 10, 5);
                df = f1-2.*f2;
                bwo = conv2(app.ImCurrent2, df, 'same');
                bwo = imcomplement(bwo);
                bwo = bwo./max(max(bwo));
                th = graythresh(bwo)+app.Thresh/1000;
                app.ImMask = imbinarize(bwo,th);
                app.ImMask = bwareaopen(app.ImMask,round(app.IW*app.IH/10));
                se = strel('disk',10);
                app.ImMask = imclose(app.ImMask,se);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
                hold(app.UIAxes2,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes2,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes2,'off')
            end
        end

        % Button pushed function: cropButton
        function cropButtonPushed(app, event)
            bwl = logical(app.ImMask);
            s = regionprops(bwl, 'BoundingBox');
            s.BoundingBox(1) = s.BoundingBox(1)-ceil(app.WindowSize/2);
            s.BoundingBox(2) = s.BoundingBox(2)-ceil(app.WindowSize/2);
            s.BoundingBox(3) = s.BoundingBox(3)+app.WindowSize;
            s.BoundingBox(4) = s.BoundingBox(4)+app.WindowSize;
            app.ImCurrent1 = imcrop(app.ImCurrent1,s.BoundingBox);
            si = size(app.ImCurrent1);
            app.IW = si(1);
            app.IH = si(2);
            app.ImCurrent2 = imcrop(app.ImCurrent2,s.BoundingBox);
            app.IR2 = imcrop(app.IR2,s.BoundingBox);
            app.ImMask = imcrop(app.ImMask,s.BoundingBox);
            imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
            hold(app.UIAxes,'on')
            bwp = bwperim(app.ImMask);
            [r,c] = find(bwp==1,1);
            if ~isempty(r)
                contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                plot(app.UIAxes,contour(:,2),contour(:,1),'g','LineWidth',0.5);
            end
            hold(app.UIAxes,'off')
            imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Dist = 1+changingValue/10;
            imshow(app.ImMask.*app.ImCurrent1,[],'Parent',app.UIAxes);
            findpeaks(app);
            findpeaks2(app);
            hold(app.UIAxes,'on')
            %plot(app.UIAxes,app.Peaks(1:2:end),app.Peaks(2:2:end),'r+')
            scatter(app.UIAxes,app.Peaks(1:2:end),app.Peaks(2:2:end),25,'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            hold(app.UIAxes,'off')
            %IM=getframe(app.UIAxes,[floor(app.IW/2)-50,floor(app.IH/2)-50,100,100]);
            %imshow(IM.cdata,'Parent',app.UIAxes_coloc)
            %axis(app.UIAxes_coloc,'tight')
            imshow(app.ImMask.*app.ImCurrent2,[],'Parent',app.UIAxes2);
            hold(app.UIAxes2,'on')
            %plot(app.UIAxes2,app.Peaks(1:2:end),app.Peaks(2:2:end),'r+')
            %scatter(app.UIAxes2,app.Peaks(1:2:end),app.Peaks(2:2:end),5,'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            hold(app.UIAxes2,'off')
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.Th = changingValue/100;
            %app.ImCurrent1 = medfilt2(app.ImCurrent1,[3,3]);
            imshow(app.ImMask.*app.ImCurrent1,[],'Parent',app.UIAxes);
            findpeaks(app);
            findpeaks2(app);
            hold(app.UIAxes,'on')
            %plot(app.UIAxes,app.Peaks(1:2:end),app.Peaks(2:2:end),'r+')
            scatter(app.UIAxes,app.Peaks(1:2:end),app.Peaks(2:2:end),25,'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            hold(app.UIAxes,'off')
            %IM=getframe(app.UIAxes,[floor(app.IW/2)-50,floor(app.IH/2)-50,100,100]);
            %imshow(IM.cdata,'Parent',app.UIAxes_coloc)
            %axis(app.UIAxes_coloc,'tight')
            imshow(app.ImMask.*app.ImCurrent2,[],'Parent',app.UIAxes2);
            hold(app.UIAxes2,'on')
            %plot(app.UIAxes2,app.Peaks(1:2:end),app.Peaks(2:2:end),'r+')
            %scatter(app.UIAxes2,app.Peaks(1:2:end),app.Peaks(2:2:end),5,'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            hold(app.UIAxes2,'off')
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            value = app.WindowsizeEditField.Value;
            app.WindowSize = value;
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            f = waitbar(0.1,'processing...');
            % averaging images and getting peak values
            cs = floor(app.WindowSize/2); % crop size
            imtot1 = zeros(app.WindowSize+1,app.WindowSize+1,app.NP);
            imtot2 = zeros(app.WindowSize+1,app.WindowSize+1,app.NP);
            j = 1;
            peak1 = [];
            peak2 = [];           
            for i=1:2:2*app.NP
                if (app.Peaks(i)<app.IH-app.WindowSize+1)&&(app.Peaks(i)>app.WindowSize+1)&&(app.Peaks(i+1)<app.IW-app.WindowSize+1)&&(app.Peaks(i+1)>app.WindowSize+1)
                    rect = [app.Peaks(i)-cs,app.Peaks(i+1)-cs,app.WindowSize,app.WindowSize];
                    imtot1(:,:,j) = imcrop(app.ImCurrent1,rect);
                    imtot2(:,:,j) = imcrop(app.ImCurrent2,rect);
                    imforpeak1 = medfilt2(imtot1(:,:,j),[3,3]);
                    imforpeak2 = medfilt2(imtot2(:,:,j),[3,3]);
                    %peaktemp1 = mean(mean(imforpeak1(cs:cs+1,cs:cs+1)));
                    %peaktemp2 = mean(mean(imforpeak2(cs:cs+1,cs:cs+1)));
                    %peaktemp1 = imforpeak1(cs+1,cs+1);
                    %peaktemp2 = imforpeak2(cs+1,cs+1);
                    %bckgtemp1 = mean(mean(imforpeak1(1:5,:)))+mean(mean(imforpeak1(end-5:end,:)))+mean(mean(imforpeak1(5:end-5,1:5)))+mean(mean(imforpeak1(5:end-5,end-5:end)));
                    %bckgtemp2 = mean(mean(imforpeak2(1:5,:)))+mean(mean(imforpeak2(end-5:end,:)))+mean(mean(imforpeak2(5:end-5,1:5)))+mean(mean(imforpeak2(5:end-5,end-5:end)));
                    %peak1 = [peak1,peaktemp1/bckgtemp1*4];
                    %peak2 = [peak2,peaktemp2/bckgtemp2*4];
                    peak1 = [peak1,mean(mean(imforpeak1(cs:cs+1,cs:cs+1)))];
                    peak2 = [peak2,mean(mean(imforpeak2(cs:cs+1,cs:cs+1)))];
                    j=j+1;
                end
            end
            immean1 = mean(imtot1,3);
            immean2 = mean(imtot2,3);
            imshow(immean1,[],'Parent',app.UIAxes_Signal1);
            colormap(app.UIAxes_Signal1,'jet')
            lim1 = caxis(app.UIAxes_Signal1);
            imshow(immean2,[],'Parent',app.UIAxes_Signal2);
            colormap(app.UIAxes_Signal2,'jet')
            lim2 = caxis(app.UIAxes_Signal2);
            % randomize
            peakrand1 = [];
            peakrand2 = [];
            for nr=1:app.Nrep
                randpeaksX = [];
                randpeaksY = [];
                i = 1;
                %hold(app.UIAxes2,'on')
                %imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
                while i<=app.NP
                    y = floor(app.IW*rand)+1; % ligne/row
                    x = floor(app.IH*rand)+1; % colonne/column
                    if app.ImMask(y,x)==1 % (row,column)
                        randpeaksX(i) = x;
                        randpeaksY(i) = y;
                        i = i+1;   
                        %plot(app.UIAxes2,x,y,'r+')
                    end
                end
                %hold(app.UIAxes2,'off')
                imtotrand1 = zeros(app.WindowSize+1,app.WindowSize+1,app.NP);
                imtotrand2 = zeros(app.WindowSize+1,app.WindowSize+1,app.NP);
                j = 1;
                for i=1:app.NP
                    if (randpeaksX(i)<app.IH-app.WindowSize+1)&&(randpeaksX(i)>app.WindowSize+1)&&(randpeaksY(i)<app.IW-app.WindowSize+1)&&(randpeaksY(i)>app.WindowSize+1)
                        rect = [randpeaksX(i)-cs,randpeaksY(i)-cs,app.WindowSize,app.WindowSize];
                        imtotrand1(:,:,j) = imcrop(app.ImCurrent1,rect);
                        %imshow(imtotrand1(:,:,j),[]);pause(1)
                        imtotrand2(:,:,j) = imcrop(app.ImCurrent2,rect);
                        imforpeak1 = medfilt2(imtotrand1(:,:,j),[3,3]);
                        imforpeak2 = medfilt2(imtotrand2(:,:,j),[3,3]);
                        %peaktemp1 = imforpeak1(cs+1,cs+1);
                        %peaktemp2 = imforpeak2(cs+1,cs+1);
                        %bckgtemp1 = mean(mean(imforpeak1(1:5,:)))+mean(mean(imforpeak1(end-5:end,:)))+mean(mean(imforpeak1(5:end-5,1:5)))+mean(mean(imforpeak1(5:end-5,end-5:end)));
                        %bckgtemp2 = mean(mean(imforpeak2(1:5,:)))+mean(mean(imforpeak2(end-5:end,:)))+mean(mean(imforpeak2(5:end-5,1:5)))+mean(mean(imforpeak2(5:end-5,end-5:end)));
                        %peakrand1 = [peakrand1,peaktemp1/bckgtemp1*4];
                        %peakrand2 = [peakrand2,peaktemp2/bckgtemp2*4];
                        peakrand1 = [peakrand1,mean(mean(imforpeak1(cs:cs+1,cs:cs+1)))];
                        peakrand2 = [peakrand2,mean(mean(imforpeak2(cs:cs+1,cs:cs+1)))];
                        j=j+1;
                    end
                end
                immeanrandtemp1(:,:,nr) = mean(imtotrand1,3);
                immeanrandtemp2(:,:,nr) = mean(imtotrand2,3);
            end
            immeanrand1 = mean(immeanrandtemp1,3);
            immeanrand2 = mean(immeanrandtemp2,3);
            imshow(immeanrand1,[],'Parent',app.UIAxes_Random1);
            colormap(app.UIAxes_Random1,'jet')
            caxis(app.UIAxes_Random1,lim1);
            imshow(immeanrand2,[],'Parent',app.UIAxes_Random2);
            colormap(app.UIAxes_Random2,'jet')
            caxis(app.UIAxes_Random2,lim2);

            % get peaks values for channel 2
            peakC2 = [];           
            for i=1:2:2*app.NP2
                if (app.Peaks2(i)<app.IH-app.WindowSize+1)&&(app.Peaks2(i)>app.WindowSize+1)&&(app.Peaks2(i+1)<app.IW-app.WindowSize+1)&&(app.Peaks2(i+1)>app.WindowSize+1)
                    rect = [app.Peaks2(i)-cs,app.Peaks2(i+1)-cs,app.WindowSize,app.WindowSize];
                    imtot(:,:,j) = imcrop(app.IR2,rect);
                    imforpeak = medfilt2(imtot(:,:,j),[3,3]);
                    peakC2 = [peakC2,mean(mean(imforpeak(cs:cs+1,cs:cs+1)))];
                    j=j+1;
                end
            end

            waitbar(0.3,f);
            % radial profile
            % Allocate an array for the profile
            profileSums1 = zeros(1,2*cs);
            profileSums2 = zeros(1,2*cs);
            profileSumsRand1 = zeros(1,2*cs);
            profileSumsRand2 = zeros(1,2*cs);
            profileCounts = zeros(1,2*cs);
            % Scan the original image getting gray level, and scan edtImage getting distance.
            % Then add those values to the profile.
            for column = 1:app.WindowSize
            	for row = 1:app.WindowSize
            		thisDistance = round(sqrt((row-(cs+1))^2 + (column-(cs+1))^2));
            		if thisDistance <= 0
            			continue;
            		end
            		profileSums1(thisDistance) = profileSums1(thisDistance) + immean1(row,column);
                    profileSums2(thisDistance) = profileSums2(thisDistance) + immean2(row,column);
                    profileSumsRand1(thisDistance) = profileSumsRand1(thisDistance) + immeanrand1(row,column);
                    profileSumsRand2(thisDistance) = profileSumsRand2(thisDistance) + immeanrand2(row,column);
            		profileCounts(thisDistance) = profileCounts(thisDistance) + 1;
            	end
            end
            % Divide the sums by the counts at each distance to get the average profile
            averageRadialProfile1 = profileSums1 ./ profileCounts;
            averageRadialProfile2 = profileSums2 ./ profileCounts;
            averageRadialProfileRand1 = profileSumsRand1 ./ profileCounts;
            averageRadialProfileRand2 = profileSumsRand2 ./ profileCounts;
            plot(app.UIAxes_Plot,1:length(averageRadialProfile1),averageRadialProfile1,'r','linewidth',2)
            hold(app.UIAxes_Plot,'on')
            plot(app.UIAxes_Plot,1:length(averageRadialProfile2),averageRadialProfile2,'b','linewidth',2)
            plot(app.UIAxes_Plot,1:length(averageRadialProfileRand1),averageRadialProfileRand1,'color',[1,0.5,0.5],'linewidth',2)
            plot(app.UIAxes_Plot,1:length(averageRadialProfileRand2),averageRadialProfileRand2,'color',[0.5,0.5,1],'linewidth',2)
            xlim(app.UIAxes_Plot,[1,cs]);
            hold(app.UIAxes_Plot,'off')
            app.RadialProfile = [averageRadialProfile1',averageRadialProfile2',averageRadialProfileRand1',averageRadialProfileRand2'];
            
            waitbar(0.4,f);
            % box plots
            cla(app.UIAxes_BoxPlot)
            hold(app.UIAxes_BoxPlot,'on')
            %Y1 = [peak1',peakrand1'];
            %Y2 = [peak2',peakrand2'];
            boxchart(1.*ones(size(peak1'))',peak1','BoxFaceColor','r','MarkerStyle','.','Parent',app.UIAxes_BoxPlot)
            boxchart(2.*ones(size(peakrand1'))',peakrand1','BoxFaceColor',[1,0.5,0.5],'MarkerStyle','none','Parent',app.UIAxes_BoxPlot)
            boxchart(3.*ones(size(peak2'))',peak2','BoxFaceColor','b','MarkerStyle','none','Parent',app.UIAxes_BoxPlot)
            boxchart(4.*ones(size(peakrand2'))',peakrand2','BoxFaceColor',[0.5,0.5,1],'MarkerStyle','.','Parent',app.UIAxes_BoxPlot)
            ylim(app.UIAxes_BoxPlot,'tight');
            yl = ylim(app.UIAxes_BoxPlot);
            ylim(app.UIAxes_BoxPlot,[yl(1),yl(2)+0.2]);
            xticks(app.UIAxes_BoxPlot,[1:4]);
            xticklabels(app.UIAxes_BoxPlot,{'Sig1','Rand1','Sig2','Rand2'})
            % statistical test
            [h,p1] = ttest2(peak1,peakrand1);
            if p1<=1E-3
                stars='***';
            elseif p1<=1E-2
                stars='**';
            elseif p1<=0.05
                stars='*';
            elseif isnan(p1)
                stars='n.s.';
            else
                stars='';
            end
            plot(app.UIAxes_BoxPlot,[1,2],[yl(2)+0.05,yl(2)+0.05],'-k','LineWidth',1.5)
            text(app.UIAxes_BoxPlot,1.5,yl(2)+0.1,stars,'HorizontalAlignment','Center','BackGroundColor','none','FontSize',12);
            [h,p2] = ttest2(peak2,peakrand2);
            if p2<=1E-3
                stars='***';
            elseif p2<=1E-2
                stars='**';
            elseif p2<=0.05
                stars='*';
            elseif isnan(p1)
                stars='n.s.';
            else
                stars='';
            end
            plot(app.UIAxes_BoxPlot,[3,4],[yl(2)+0.05,yl(2)+0.05],'-k','LineWidth',1.5)
            text(app.UIAxes_BoxPlot,3.5,yl(2)+0.1,stars,'HorizontalAlignment','Center','BackGroundColor','none','FontSize',12);
            hold(app.UIAxes_BoxPlot,'off')
            app.AllPeaks = [peak1',peak2'];
            app.AllPeaks2 = peakC2';
            app.AllRandomPeaks = [peakrand1',peakrand2'];

            waitbar(0.5,f);
            % histogram
            cla(app.UIAxes_Hist)
            hold(app.UIAxes_Hist,'on')
            h2 = histogram(app.UIAxes_Hist,peak2,'FaceColor','b','BinWidth',0.05,'Orientation','horizontal','Normalization','probability');
            binlim = h2.BinLimits;
            h1 = histogram(app.UIAxes_Hist,peakrand2,'FaceColor',[0.5,0.5,0.5],'BinWidth',0.05,'Orientation','horizontal','Normalization','probability');
            h1.BinLimits = binlim;
            perc95 = prctile(peakrand2,95);
            %plot(app.UIAxes_Hist,[1 20],perc95,'k')
            npp = find(peak2>=perc95);
            npp = length(npp);
            app.Fpp = round(100*npp/length(peak2));
            yl = ylim(app.UIAxes_Hist);
            ylim(app.UIAxes_Hist,[yl(1),yl(2)+0.2]);
            text(app.UIAxes_Hist,0.025,yl(2)+0.05,[num2str(app.Fpp),' % strickly positive'],'BackGroundColor','none','FontSize',12);
            diffh = (h2.Values - h1.Values);
            Fmp = round(100*(sum(diffh(diffh>0))));
            text(app.UIAxes_Hist,0.025,yl(2)+0.15,[num2str(Fmp),' % positive'],'BackGroundColor','none','FontSize',12);
            hold(app.UIAxes_Hist,'off')

            % scatter
            cla(app.UIAxes_Scatter)
            hold(app.UIAxes_Scatter,'on')
            scatter(app.UIAxes_Scatter,peak1,peak2,'filled','MarkerFaceAlpha',0.5,'MarkerEdgeAlpha',0.5)
            axis(app.UIAxes_Scatter,'equal')
            hold(app.UIAxes_Scatter,'off')

            waitbar(0.7,f);
            % Simple colocalization
            % assess colocalization
            x1p = app.Peaks(1:2:end);
            y1p = app.Peaks(2:2:end);
            x2p = app.Peaks2(1:2:end);
            y2p = app.Peaks2(2:2:end);
            count = 0;
            pcx = [];
            pcy = [];
            for i=1:app.NP
                distances = sqrt((x1p(i)-x2p).^2+(y1p(i)-y2p).^2);
                mindist = min(distances);
                if mindist<app.MaxDist
                    pcx(i) = x1p(i);
                    pcy(i) = y1p(i);
                    count =count+1;
                end
            end
            app.FColoc = count/app.NP;

            % randomized
            %             count = 0;
            %             x2p = randpeaksX(i);
            %             y2p = randpeaksY(i);
            %             for i=1:app.NP
            %                 distances = sqrt((x1p-x2p).^2+(y1p-y2p).^2);
            %                 mindist = min(distances);
            %                 if mindist<3
            %                     count =count+1;
            %                 end
            %             end
            idx = find(app.ImMask==1);
            idx = length(idx);
            app.FColocRand = pi*app.MaxDist^2/idx*app.NP2;

            waitbar(1,f);
            % plot colocalization
            cla(app.UIAxes_coloc)
            hold(app.UIAxes_coloc,'on')
            scatter(app.UIAxes_coloc,app.Peaks(1:2:end),-app.Peaks(2:2:end),5,'MarkerEdgeColor','r','MarkerFaceColor','r','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            scatter(app.UIAxes_coloc,app.Peaks2(1:2:end),-app.Peaks2(2:2:end),5,'MarkerEdgeColor','b','MarkerFaceColor','b','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            plot(app.UIAxes_coloc,pcx,-pcy,'ko','MarkerSize',5)
            yl = ylim(app.UIAxes_coloc);
            xl = xlim(app.UIAxes_coloc);
            axis(app.UIAxes_coloc,'equal')
            text(app.UIAxes_coloc,xl(1)+10,yl(2)-10,[num2str(round(app.FColoc*100)),' % peak colocalization, ',num2str(round(app.FColocRand*100)),' % colocalization by chance'],'BackGroundColor','none','FontSize',12);
            hold(app.UIAxes_coloc,'off')
            hold(app.UIAxes2,'on')
            %plot(app.UIAxes2,app.Peaks2(1:2:end),app.Peaks2(2:2:end),'b+')
            scatter(app.UIAxes2,app.Peaks2(1:2:end),app.Peaks2(2:2:end),25,'MarkerEdgeColor','b','MarkerFaceColor','b','MarkerFaceAlpha',.6,'MarkerEdgeAlpha',.6)
            plot(app.UIAxes2,pcx,pcy,'ro','MarkerSize',15)
            hold(app.UIAxes2,'off')
            hold(app.UIAxes,'on')
            %plot(app.UIAxes,app.Peaks2(1:2:end),app.Peaks2(2:2:end),'b+')
            plot(app.UIAxes,pcx,pcy,'ro','MarkerSize',15)
            hold(app.UIAxes,'off')

            close(f)
        end

        % Value changed function: NbootstrapEditField
        function NbootstrapEditFieldValueChanged(app, event)
            value = app.NbootstrapEditField.Value;
            app.Nrep = value;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save to text: parameters
            f = waitbar(0,'Saving parameters...');
            filenametxt = [app.FileName1(1:end-4),'_PARAMETERS.txt'];
            T = table(app.Thresh,app.Dist,app.Th,app.NP,app.Nrep,app.WindowSize,app.Fpp,app.FColoc,'VariableNames', {'threshold segmentation','peaks distance','threshold peaks','number of peaks','number of repetitions','window size','Fraction of positive peaks','Fraction of colocalization'});
            writetable(T,[app.PathName1,filenametxt]);
            % save to text: peak positions
            waitbar(0.1,f,'Saving peak postions...');
            filenametxt = [app.FileName1(1:end-4),'_PEAKS.txt'];
            peaks = app.Peaks(1:2:end);
            peaks = [peaks,app.Peaks(2:2:end)];
            writematrix(peaks,[app.PathName1,filenametxt]);
            % save to xlsx: radial profiles and peak values
            waitbar(0.2,f,'Saving profiles and peaks...');
            filename = [app.FileName1(1:end-4),'_PROFILES.xlsx'];
            writematrix(app.RadialProfile,[app.PathName1,filename],'Sheet',1,'Range','A1');
            writematrix(app.AllPeaks,[app.PathName1,filename],'Sheet',2,'Range','A1');
            writematrix(app.AllRandomPeaks,[app.PathName1,filename],'Sheet',2,'Range','C1');
            writematrix(app.AllPeaks2,[app.PathName1,filename],'Sheet',2,'Range','E1');
            % save graphics
            waitbar(0.3,f,'Saving graphics 1...');
            filename = [app.FileName1(1:end-4),'_PLOTS.png'];
            exportgraphics(app.UIAxes_Plot,[app.PathName1,filename])
            waitbar(0.4,f,'Saving graphics 2...');
            filename = [app.FileName1(1:end-4),'_BOXPLOT.png'];
            exportgraphics(app.UIAxes_BoxPlot,[app.PathName1,filename])
            waitbar(0.5,f,'Saving graphics 3...');
            filename = [app.FileName1(1:end-4),'_SIGNAL1.png'];
            exportgraphics(app.UIAxes_Signal1,[app.PathName1,filename])
            waitbar(0.6,f,'Saving graphics 4...');
            filename = [app.FileName1(1:end-4),'_SIGNAL2.png'];
            exportgraphics(app.UIAxes_Signal2,[app.PathName1,filename])
            waitbar(0.7,f,'Saving graphics 5...');
            filename = [app.FileName1(1:end-4),'_RANDOM1.png'];
            exportgraphics(app.UIAxes_Random1,[app.PathName1,filename])
            waitbar(0.8,f,'Saving graphics 6...');
            filename = [app.FileName1(1:end-4),'_RANDOM2.png'];
            exportgraphics(app.UIAxes_Random2,[app.PathName1,filename])
            waitbar(0.9,f,'Saving graphics 7...');
            filename = [app.FileName1(1:end-4),'_HISTO.png'];
            exportgraphics(app.UIAxes_Hist,[app.PathName1,filename])
            filename = [app.FileName1(1:end-4),'_SCATTER.png'];
            exportgraphics(app.UIAxes_Scatter,[app.PathName1,filename])
            filename = [app.FileName1(1:end-4),'_COLOC.png'];
            exportgraphics(app.UIAxes_coloc,[app.PathName1,filename])
            close(f)
        end

        % Value changed function: choosechanelDropDown
        function choosechanelDropDownValueChanged(app, event)
            app.ChannelToSegment = app.choosechanelDropDown.Value;            
            if app.ChannelToSegment=='Channel 1'
                imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
                f1 = fspecial('Gaussian', 10,3);
                f2 = fspecial('Gaussian', 10, 5);
                df = f1-2.*f2;
                bwo = conv2(app.ImCurrent1, df, 'same');
                bwo = imcomplement(bwo);
                bwo = bwo./max(max(bwo));
                th = graythresh(bwo)+app.Thresh/1000;
                app.ImMask = imbinarize(bwo,th);
                app.ImMask = bwareaopen(app.ImMask,round(app.IW*app.IH/5));
                se = strel('disk',10);
                app.ImMask = imclose(app.ImMask,se);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
                hold(app.UIAxes,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes,'off')
            else
                imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
                f1 = fspecial('Gaussian', 10,3);
                f2 = fspecial('Gaussian', 10, 5);
                df = f1-2.*f2;
                bwo = conv2(app.ImCurrent2, df, 'same');
                bwo = imcomplement(bwo);
                bwo = bwo./max(max(bwo));
                th = graythresh(bwo)+app.Thresh/1000;
                app.ImMask = imbinarize(bwo,th);
                app.ImMask = bwareaopen(app.ImMask,round(app.IW*app.IH/5));
                se = strel('disk',10);
                app.ImMask = imclose(app.ImMask,se);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
                hold(app.UIAxes2,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes2,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes2,'off')
            end
        end

        % Button pushed function: ManualsegmentationButton
        function ManualsegmentationButtonPushed(app, event)
            ftemp = figure;
            if app.ChannelToSegment=='Channel 1'
                imshow(app.ImCurrent1,[]);
                roi = images.roi.AssistedFreehand;
                draw(roi);
                app.ImMask = createMask(roi);
                close(ftemp);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent1,[],'Parent',app.UIAxes);
                hold(app.UIAxes,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes,'off')
            else
                imshow(app.ImCurrent2,[]);
                roi = images.roi.AssistedFreehand;
                draw(roi);
                app.ImMask = createMask(roi);
                close(ftemp);
                app.ImMask = imfill(app.ImMask,'holes');
                bwp = bwperim(app.ImMask);
                [r,c] = find(bwp==1,1);
                imshow(app.ImCurrent2,[],'Parent',app.UIAxes2);
                hold(app.UIAxes2,'on')
                if ~isempty(r)
                    contour = bwtraceboundary(app.ImMask,[r c],'W',8,Inf,'counterclockwise');
                    plot(app.UIAxes2,contour(:,2),contour(:,1),'g','LineWidth',0.5);
                end
                hold(app.UIAxes2,'off')
            end
        end

        % Value changing function: maxdistSlider
        function maxdistSliderValueChanging(app, event)
            app.MaxDist = event.Value;
            % assess colocalization
            x1p = app.Peaks(1:2:end);
            y1p = app.Peaks(2:2:end);
            x2p = app.Peaks2(1:2:end);
            y2p = app.Peaks2(2:2:end);
            count = 0;
            pcx = [];
            pcy = [];
            for i=1:app.NP
                distances = sqrt((x1p(i)-x2p).^2+(y1p(i)-y2p).^2);
                mindist = min(distances);
                if mindist<app.MaxDist
                    pcx(i) = x1p(i);
                    pcy(i) = y1p(i);
                    count =count+1;
                end
            end
            app.FColoc = count/app.NP;

            % randomized
            %             count = 0;
            %             x2p = randpeaksX(i);
            %             y2p = randpeaksY(i);
            %             for i=1:app.NP
            %                 distances = sqrt((x1p-x2p).^2+(y1p-y2p).^2);
            %                 mindist = min(distances);
            %                 if mindist<3
            %                     count =count+1;
            %                 end
            %             end
            idx = find(app.ImMask==1);
            idx = length(idx);
            app.FColocRand = pi*app.MaxDist^2/idx*app.NP2;

            % plot colocalization
            cla(app.UIAxes_coloc)
            hold(app.UIAxes_coloc,'on')
            plot(app.UIAxes_coloc,app.Peaks(1:2:end),-app.Peaks(2:2:end),'r+')
            plot(app.UIAxes_coloc,app.Peaks2(1:2:end),-app.Peaks2(2:2:end),'b+')
            plot(app.UIAxes_coloc,pcx,-pcy,'go','MarkerSize',5)
            yl = ylim(app.UIAxes_coloc);
            xl = xlim(app.UIAxes_coloc);
            axis(app.UIAxes_coloc,'equal')
            text(app.UIAxes_coloc,xl(1)+10,yl(2)-10,[num2str(round(app.FColoc*100)),' % peak colocalization, ',num2str(round(app.FColocRand*100)),' % colocalization by chance'],'BackGroundColor','none','FontSize',12);
            hold(app.UIAxes_coloc,'off')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,app.Peaks2(1:2:end),app.Peaks2(2:2:end),'b+')
            plot(app.UIAxes2,pcx,pcy,'go','MarkerSize',5)
            hold(app.UIAxes2,'off')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Peaks2(1:2:end),app.Peaks2(2:2:end),'b+')
            plot(app.UIAxes,pcx,pcy,'go','MarkerSize',5)
            hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.focianalysisUIFigure = uifigure('Visible', 'off');
            app.focianalysisUIFigure.Name = 'foci analysis';
            app.Panel = uipanel(app.focianalysisUIFigure);
            app.pathfileEditFieldLabel = uilabel(app.Panel);
            app.pathfileEditFieldLabel.Text = 'path/file';
            app.pathfileEditField = uieditfield(app.Panel, 'text');
            app.pathfileEditField.Value = 'path';
            app.loadChannel1Button = uibutton(app.Panel, 'push');
            app.loadChannel1Button.ButtonPushedFcn = createCallbackFcn(app, @loadChannel1ButtonPushed, true);
            app.loadChannel1Button.Text = 'load Channel 1';
            app.loadChannel2Button = uibutton(app.Panel, 'push');
            app.loadChannel2Button.ButtonPushedFcn = createCallbackFcn(app, @loadChannel2ButtonPushed, true);
            app.loadChannel2Button.Text = 'load Channel 2';
            app.pathfileEditField_2Label = uilabel(app.Panel);
            app.pathfileEditField_2Label.Text = 'path/file';
            app.pathfileEditField_2 = uieditfield(app.Panel, 'text');
            app.pathfileEditField_2.Value = 'path';
            app.Step1segmentnucleiPanel = uipanel(app.focianalysisUIFigure);
            app.Step1segmentnucleiPanel.Title = 'Step 1: segment nuclei';
            app.choosechanelDropDownLabel = uilabel(app.Step1segmentnucleiPanel);
            app.choosechanelDropDownLabel.Text = 'choose chanel';
            app.choosechanelDropDown = uidropdown(app.Step1segmentnucleiPanel);
            app.choosechanelDropDown.Items = {'Channel 1', 'Channel 2'};
            app.choosechanelDropDown.ValueChangedFcn = createCallbackFcn(app, @choosechanelDropDownValueChanged, true);
            app.choosechanelDropDown.Value = 'Channel 1';
            app.Slider = uislider(app.Step1segmentnucleiPanel);
            app.Slider.Limits = [-20 100];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ManualsegmentationButton = uibutton(app.Step1segmentnucleiPanel, 'push');
            app.ManualsegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualsegmentationButtonPushed, true);
            app.ManualsegmentationButton.Text = 'Manual segmentation';
            app.Step2optionalPanel = uipanel(app.focianalysisUIFigure);
            app.Step2optionalPanel.Title = 'Step 2 (optional)';
            app.cropButton = uibutton(app.Step2optionalPanel, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Text = 'crop';
            app.Step3findpeaksPanel = uipanel(app.focianalysisUIFigure);
            app.Step3findpeaksPanel.Title = 'Step 3: find peaks';
            app.distancebetweenpeaksLabel = uilabel(app.Step3findpeaksPanel);
            app.distancebetweenpeaksLabel.Text = 'distance between peaks';
            app.thresholdforpeaksLabel = uilabel(app.Step3findpeaksPanel);
            app.thresholdforpeaksLabel.Text = 'threshold for peaks';
            app.Slider_2 = uislider(app.Step3findpeaksPanel);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_3 = uislider(app.Step3findpeaksPanel);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Panel_2 = uipanel(app.focianalysisUIFigure);
            app.UIAxes2 = uiaxes(app.Panel_2);
            app.UIAxes = uiaxes(app.Panel_2);
            app.Step4analyzePanel = uipanel(app.focianalysisUIFigure);
            app.Step4analyzePanel.Title = 'Step 4: analyze';
            app.UIAxes_Plot = uiaxes(app.Step4analyzePanel);
            title(app.UIAxes_Plot, 'Spatial distribution')
            xlabel(app.UIAxes_Plot, 'pixels')
            ylabel(app.UIAxes_Plot, 'intensity')
            app.UIAxes_BoxPlot = uiaxes(app.Step4analyzePanel);
            title(app.UIAxes_BoxPlot, 'peak value')
            ylabel(app.UIAxes_BoxPlot, 'peak max intensity')
            app.UIAxes_BoxPlot.XTick = [0 0.5 1];
            app.UIAxes_Hist = uiaxes(app.Step4analyzePanel);
            title(app.UIAxes_Hist, 'Peak dsitribution')
            xlabel(app.UIAxes_Hist, 'counts')
            ylabel(app.UIAxes_Hist, 'peaks')
            app.UIAxes_Scatter = uiaxes(app.Step4analyzePanel);
            title(app.UIAxes_Scatter, 'Peak vs peak intensities')
            xlabel(app.UIAxes_Scatter, 'channel 1')
            ylabel(app.UIAxes_Scatter, 'chanel 2')
            app.UIAxes_Random1 = uiaxes(app.Step4analyzePanel);
            app.UIAxes_Random2 = uiaxes(app.Step4analyzePanel);
            app.UIAxes_Signal2 = uiaxes(app.Step4analyzePanel);
            app.UIAxes_Signal1 = uiaxes(app.Step4analyzePanel);
            app.ProcessButton = uibutton(app.Step4analyzePanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.WindowsizeEditFieldLabel = uilabel(app.Step4analyzePanel);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.Step4analyzePanel, 'numeric');
            app.WindowsizeEditField.Limits = [1 Inf];
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 20;
            app.NbootstrapEditFieldLabel = uilabel(app.Step4analyzePanel);
            app.NbootstrapEditFieldLabel.Text = 'N bootstrap';
            app.NbootstrapEditField = uieditfield(app.Step4analyzePanel, 'numeric');
            app.NbootstrapEditField.Limits = [1 Inf];
            app.NbootstrapEditField.ValueChangedFcn = createCallbackFcn(app, @NbootstrapEditFieldValueChanged, true);
            app.NbootstrapEditField.Value = 1;
            app.Channel1Label = uilabel(app.Step4analyzePanel);
            app.Channel1Label.Text = 'Channel 1';
            app.Channel2Label = uilabel(app.Step4analyzePanel);
            app.Channel2Label.Text = 'Channel 2';
            app.signalLabel = uilabel(app.Step4analyzePanel);
            app.signalLabel.Text = 'signal';
            app.randomizedLabel = uilabel(app.Step4analyzePanel);
            app.randomizedLabel.Text = 'randomized';
            app.Panel_3 = uipanel(app.focianalysisUIFigure);
            app.UIAxes_coloc = uiaxes(app.Panel_3);
            title(app.UIAxes_coloc, 'Peaks colocalization')
            app.maxdistSliderLabel = uilabel(app.Panel_3);
            app.maxdistSliderLabel.Text = 'max dist';
            app.maxdistSlider = uislider(app.Panel_3);
            app.maxdistSlider.Limits = [0 10];
            app.maxdistSlider.ValueChangingFcn = createCallbackFcn(app, @maxdistSliderValueChanging, true);
            app.maxdistSlider.Value = 3;
            app.Step5SavingPanel = uipanel(app.focianalysisUIFigure);
            app.Step5SavingPanel.Title = 'Step 5: Saving';
            app.SaveButton = uibutton(app.Step5SavingPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.focianalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = foci_analizer_V3_forfig

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.focianalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.focianalysisUIFigure)
        end
    end
end